classdef ImageProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxesInput           matlab.ui.control.UIAxes
        UIAxesOutput          matlab.ui.control.UIAxes
        uploadButton          matlab.ui.control.Button
        DownloadButton        matlab.ui.control.Button
        CompressButton        matlab.ui.control.Button
        EqualisationButton    matlab.ui.control.Button
        GussianNoiseButton    matlab.ui.control.Button
        AverageDenoiseButton  matlab.ui.control.Button
        PoissonNoiseButton    matlab.ui.control.Button
        PauseNoiseButton      matlab.ui.control.Button
        SpeckleNoiseButton    matlab.ui.control.Button
        MedianDenoiseButton   matlab.ui.control.Button
        MosaicButton          matlab.ui.control.Button
        EdgeDetectButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        % Call imformats to check the image formats supported by Matlab
        extensions = {'.bmp';'.cur';'.fts';'.fits';'.hdf';'.ico';'.j2c';'.j2k';'.jp2';'.jp2';'.xwd';
            '.jpf';'.jpx';'.jpg';'.jpeg';'.pbm';'.pcx';'.pgm';'.png';'.pnm';'.ppm';'.ras';'.tif';'.tiff';}
        im_data % image data read by imread
        im_data_name % the file name of the image
        processed_data % image data after been processed
        count=0 % Record the number of images been processed
    end
    
    methods (Access = private)
        % DCT Compression funtion
        function compim = im2jpeg(app)
            %IM2DCTJPEG Compresses an image using a JPEG approximation.
            % COMPIM = IM2DCTJPEG(image) compresses input image based on
            % 8*8 discrete cosine transform and coefficient quantization
                
            ycbcrIm = rgb2ycbcr(app.im_data); % Convert rgb image to lumina and chroma
            [row,col,~] = size(ycbcrIm); 
            m = 8; % size of non-overlapping blocks
            % Change the size of the image to an integral multiple of 8
            halfrow = row/2;
            halfcol = col/2;
            mhalfrow = ceil(halfrow/m)*m;
            mhalfcol = ceil(halfcol/m)*m;
            mr = mhalfrow*2;
            mc = mhalfcol*2;
            ycbcrIm = imresize(ycbcrIm,[mr mc]);
            
            % Using 4:2:0 sampling to Y,Cb,Cr channels respectively
            YChannel = ycbcrIm(:,:,1);  % Sampling all luminance values
            % In the Cb channel, for every two rows(4 values for each)
            % only take the firt and the third values of the first row
            CbChannel = zeros(mr/2, mc/2);
            for i=1:2:mr-1 
                for j=1:2:mc-1 
                    CbChannel((i+1)/2,(j+1)/2) = ycbcrIm(i,j,2);
                end
            end
            % In the Cr channel, for every two rows(4 values for each)
            % only take the firt and the third values of the second row
            CrChannel = zeros(mr/2, mc/2);
            for i=2:2:row
                for j=1:2:col-1 
                    CrChannel(i/2,(j+1)/2) = ycbcrIm(i,j,3);
                end
            end
            
            % Divide the image into non-overlapping 8x8 blocks
            % Compute the discrete cosine transform for each block
            dctfun = @(block_struct)dct2(block_struct.data);
            dctY = blockproc(YChannel,[8 8],dctfun);
            dctCb = blockproc(CbChannel,[8 8],dctfun);
            dctCr = blockproc(CrChannel,[8 8],dctfun);
            
            maskY = [ 
            16  11  10  16   24   40   51   61
            12  12  14  19   26   58   60   55
            14  13  16  24   40   57   69   55
            14  17  22  29   51   87   80   62
            18  22  37  56   68  109  103   77 
            24  35  55  64   81  104  113   92                      
            49  64  78  87  103  121  120  101                   
            72  92  95  98  112  100  103   99 ]; % Jpeg normalisation array for luminance
            
            maskCbCr = [
            17  18  24  47  99  99  99  99
            18  21  26  66  99  99  99  99
            24  26  56  99  99  99  99  99
            47  66  99  99  99  99  99  99
            99  99  99  99  99  99  99  99
            99  99  99  99  99  99  99  99
            99  99  99  99  99  99  99  99
            99  99  99  99  99  99  99  99 ]; % Quantization table for chroma
            
            % Quantization: each 8*8 block of dctIm coefficient
            % is divided by the mask and take the integer
            funY = @(block_struct)round(block_struct.data./maskY);
            quantY = blockproc(dctY,[8 8],funY);
            funCbCr = @(block_struct)round(block_struct.data./maskCbCr);
            quantCb = blockproc(dctCb,[8 8],funCbCr);
            quantCr = blockproc(dctCr,[8 8],funCbCr);
            
            order = [  
             1  9   2   3  10  17  25  18 ...
            11  4   5  12  19  26  33  41 ... 
            34 27  20  13   6   7  14  21 ...
            28 35  42  49  57  50  43  36 ... 
            29 22  15   8  16  23  30  37 ...
            44 51  58  59  52  45  38  31 ...
            24 32  39  46  53  60  61  54 ...
            47 40  48  55  62  63  56  64 ]; % Zigzag reordering pattern
            % Reorder Y channel
            zzY = im2col(quantY,[8 8],'distinct'); % Break 8*8 blocks into columns
            zzY = zzY(order,:); % Reorder column elements as order of zigzag pattern
            numOfBlocksY = size(zzY,2);
            eobY = max(zzY(:))+1; % end-of-block symble
            % Contains all non-zero value and an extra eob for each block
            % Abandon zero values to save storage space
            effectiveZZY = zeros(numel(zzY) + size(zzY,2),1);
            countY = 1;
            for blc = 1:size(zzY,2) % Procss 1 block(column in zzIm) at a time
                lastNonZeroIndex = find(zzY(:,blc),1,'last'); % Find last non-zero element
                if isempty(lastNonZeroIndex) % This block has no non-zero value
                    lastNonZeroIndex = 0;
                end
                startIndex = countY;
                endIndex = startIndex + lastNonZeroIndex;
                % Only add non-zero values and end with a eob signal
                effectiveZZY(startIndex:endIndex) = [zzY(1:lastNonZeroIndex,blc);eobY];
                countY = endIndex + 1;
            end
            effectiveZZY(countY:end) = []; % Delete unused portion
            % Reorder Cb channel
            zzCb = im2col(quantCb,[8 8],'distinct');
            zzCb = zzCb(order,:); 
            numOfBlocksCb = size(zzCb,2);
            eobCb = max(zzCb(:))+1;
            effectiveZZCb = zeros(numel(zzCb) + size(zzCb,2),1);
            countCb = 1;
            for blc = 1:size(zzCb,2) 
                lastNonZeroIndex = find(zzCb(:,blc),1,'last'); 
                if isempty(lastNonZeroIndex)
                    lastNonZeroIndex = 0;
                end
                startIndex = countCb;
                endIndex = startIndex + lastNonZeroIndex;
                effectiveZZCb(startIndex:endIndex) = [zzCb(1:lastNonZeroIndex,blc);eobCb];
                countCb = endIndex + 1;
            end
            effectiveZZCb(countCb:end) = [];
            % Reeorder Cr channel
            zzCr = im2col(quantCr,[8 8],'distinct');
            zzCr = zzCr(order,:); 
            numOfBlocksCr = size(zzCr,2);
            eobCr = max(zzCr(:))+1;
            effectiveZZCr = zeros(numel(zzCr) + size(zzCr,2),1);
            countCr = 1;
            for blc = 1:size(zzCr,2) 
                lastNonZeroIndex = find(zzCr(:,blc),1,'last'); 
                if isempty(lastNonZeroIndex)
                    lastNonZeroIndex = 0;
                end
                startIndex = countCr;
                endIndex = startIndex + lastNonZeroIndex;
                effectiveZZCr(startIndex:endIndex) = [zzCr(1:lastNonZeroIndex,blc);eobCr];
                countCr = endIndex + 1;
            end
            effectiveZZCr(countCr:end) = [];
            
            compim = struct;
            compim.Y = effectiveZZY;
            compim.Cb = effectiveZZCb;
            compim.Cr = effectiveZZCr;
            compim.numOfBlocksY = numOfBlocksY;
            compim.numOfBlocksCb = numOfBlocksCb;
            compim.numOfBlocksCr = numOfBlocksCr;
            compim.size = [mr,mc];
        end
        
        % DCT Decompression function
        function reIm = jpeg2im(app)
            % IMAGE = DCTJPEG2IM(COMPIM) decodes compressed image
            % COMPIM, generating reconstructed approximation IMAGE.
            compim= app.processed_data;
            compY = compim.Y;
            compCb = compim.Cb;
            compCr = compim.Cr;
            
            eobY = max(compY(:)); % Get end-of-block symbol
            eobCb = max(compCb(:));
            eobCr = max(compCr(:));
            % Copy values of 8*8 blocks from input compim into columns
            zerosY = zeros(64,compim.numOfBlocksY);
            zerosCb = zeros(64,compim.numOfBlocksCb);
            zerosCr = zeros(64,compim.numOfBlocksCr);
            
            i = 1;
            for col = 1:compim.numOfBlocksY
                for row = 1:64
                    if compY(i) == eobY
                        i = i+1; 
                        break;
                    else
                        zerosY(row,col) = compY(i);
                        i = i+1;
                    end    
                end
            end
            
            j = 1;
            for col = 1:compim.numOfBlocksCb
                for row = 1:64
                    if compCb(j) == eobCb
                        j = j+1; 
                        break;
                    else
                        zerosCb(row,col) = compCb(j);
                        j = j+1;
                    end    
                end
            end
            
            k = 1;
            for col = 1:compim.numOfBlocksCr
                for row = 1:64
                    if compCr(k) == eobCr
                        k = k+1; 
                        break;
                    else
                        zerosCr(row,col) = compCr(k);
                        k = k+1;
                    end    
                end
            end
            
            order = [  
             1  9   2   3  10  17  25  18 ...
            11  4   5  12  19  26  33  41 ... 
            34 27  20  13   6   7  14  21 ...
            28 35  42  49  57  50  43  36 ... 
            29 22  15   8  16  23  30  37 ...
            44 51  58  59  52  45  38  31 ...
            24 32  39  46  53  60  61  54 ...
            47 40  48  55  62  63  56  64 ];
            rev = order;
            % Compute inverse ordering 
            for k = 1:64
                rev(k) = find(order == k);
            end
            zerosY = zerosY(rev,:); % Restore order of column
            zerosCb = zerosCb(rev,:);
            zerosCr = zerosCr(rev,:);
            
            % Transform columns into matrix
            quantY = col2im(zerosY,[8 8],[compim.size(1),compim.size(2)],'distinct');
            quantCb = col2im(zerosCb,[8 8],[compim.size(1)/2,compim.size(2)/2],'distinct');
            quantCr = col2im(zerosCr,[8 8],[compim.size(1)/2,compim.size(2)/2],'distinct');
            
            % Inverse quantization
            maskY = [
            16  11  10  16   24   40   51   61
            12  12  14  19   26   58   60   55
            14  13  16  24   40   57   69   55
            14  17  22  29   51   87   80   62
            18  22  37  56   68  109  103   77 
            24  35  55  64   81  104  113   92                      
            49  64  78  87  103  121  120  101                   
            72  92  95  98  112  100  103   99 ];
            
            maskCbCr = [
            17  18  24  47  99  99  99  99
            18  21  26  66  99  99  99  99
            24  26  56  99  99  99  99  99
            47  66  99  99  99  99  99  99
            99  99  99  99  99  99  99  99
            99  99  99  99  99  99  99  99
            99  99  99  99  99  99  99  99
            99  99  99  99  99  99  99  99 ];
            
            quantfunY = @(block_struct)round(block_struct.data.*maskY);
            dctY = blockproc(quantY,[8 8],quantfunY);
            quantfunCbCr = @(block_struct)round(block_struct.data.*maskCbCr);
            dctCb = blockproc(quantCb,[8 8],quantfunCbCr);
            dctCr = blockproc(quantCr,[8 8],quantfunCbCr);
            
            % Inverse DCT
            idctfun = @(block_struct)idct2(block_struct.data);
            sampledY = blockproc(dctY,[8 8],idctfun);
            sampledCb = blockproc(dctCb,[8 8],idctfun);
            sampledCr = blockproc(dctCr,[8 8],idctfun);
            
            % Inverse sampling, fill each 2*2 blcok
            % of Cb and Cr with the first value
            ycbcrim(:,:,1) = sampledY;
            for i = 1:compim.size(1)/2
                for j = 1:compim.size(2)/2
                    ycbcrim(2*i-1,2*j-1,2) = sampledCb(i,j);
                    ycbcrim(2*i,2*j-1,2) = sampledCb(i,j);
                    ycbcrim(2*i-1,2*j,2) = sampledCb(i,j);
                    ycbcrim(2*i,2*j,2) = sampledCb(i,j);
                    ycbcrim(2*i-1,2*j-1,3) = sampledCr(i,j);
                    ycbcrim(2*i,2*j-1,3) = sampledCr(i,j);
                    ycbcrim(2*i-1,2*j,3) = sampledCr(i,j);
                    ycbcrim(2*i,2*j,3) = sampledCr(i,j); 
                end
            end
            ycbcrim = im2uint8(ycbcrim/255);
            rgbim = ycbcr2rgb(ycbcrim);
            
            reIm = struct;
            reIm.im = rgbim;
        end
        
        % Equalisation function
        function equalisedim = im2histeq(app)
            equalisedim = app.im_data;
            [row,col,dim]=size(equalisedim); % 2-d for grayscale image, 3-d for rgb image
            if dim == 1
                greyim = equalisedim;
            elseif dim == 3
                equalisedim = rgb2ycbcr(equalisedim);
                YChannel = equalisedim(:,:,1);
                greyim = YChannel;
            end
            % count is an array of the number of pixels for each grey level
            % ~ denotes number of grey level, 256 by deefault
            [pNum,~]=imhist(greyim);
            % Probability density function denotes probability for each grey level
            % Each gray level of 256 has a number of pixels, devided by total number
            % of pixels of the image
            PDF = pNum/(row*col);
            % Cumulative probability function denote the area of probability
            % distribution in a specific interval
            % Here CDF is an array of cdf from 0 to each grey level
            CDF = cumsum(PDF); 
            for i = 1: 256
                % An array of the indices of total pixels for grey level i
                greyPixelIndex = find(equalisedim(:,:,1) == i-1);
                for j = 1: length(greyPixelIndex)
                    % To the each pixel in the image that in the grel level i
                    % change their value to the cdf of i level * 255
                    % i.e. the area in the original PDF from 0 to i divided by the
                    % probability in the uniform PDF
                    % generate the pixel value in the uniform PDF
                    greyim(greyPixelIndex(j)) = round(CDF(i)*255);
                end
            end
            if dim == 1
                equalisedim = greyim;
            else
                equalisedim(:,:,1) = greyim;
                equalisedim = ycbcr2rgb(equalisedim);
            end
        end
        
        % Median filter for denoising
        function filteredim = medianfilter(app)
            noisyim = app.im_data;
            m = 3; % size of filter
            [row,col,dim]=size(noisyim);
            % Pad 0 elements by 3 rows and 3 columns around the image
            noisyim = padarray(noisyim,[m,m]);
            if dim == 1
                greyim = noisyim; % image to be processed
            elseif dim == 3 
                filteredim = rgb2ycbcr(noisyim);
                greyim = filteredim(:,:,1); 
                Cbim = filteredim(:,:,2);
                Crim = filteredim(:,:,3);
            end
            
            for i = 1: row-m+1
                for j = 1: col-m+1
                    % Extract a filter-sized block
                    filteredBlock = greyim(i:(i+m-1), j:(j+m-1));
                    filteredBlock = filteredBlock(:); % Make the block a 1-d array
                    med = median(filteredBlock); % Acquire the median value
                    % Assign the median value to the core of the block
                    greyim(i+(m-1)/2,j+(m-1)/2) = med; 
                end
            end
            
            if dim ==1
                filteredim = greyim;
            else
                for i = 1: row-m+1
                    for j = 1: col-m+1
                        filteredBlock = Cbim(i:(i+m-1), j:(j+m-1));
                        filteredBlock = filteredBlock(:);
                        med = median(filteredBlock);
                        Cbim(i+(m-1)/2,j+(m-1)/2) = med; 
                    end
                end
                for i = 1: row-m+1
                    for j = 1: col-m+1
                        filteredBlock = Crim(i:(i+m-1), j:(j+m-1));
                        filteredBlock = filteredBlock(:);
                        med = median(filteredBlock);
                        Crim(i+(m-1)/2,j+(m-1)/2) = med;
                    end
                end
                    filteredim(:,:,1) = greyim;
                    filteredim(:,:,2) = Cbim;
                    filteredim(:,:,3) = Crim;
                    filteredim = ycbcr2rgb(filteredim);
            end
        end
        % Average filter
        function filteredim = averagefilter(app)
            noisyim = app.im_data;
            m = 3; % size of filter
            noisyim = padarray(noisyim,[m,m]);
            [~,~,dim]=size(noisyim);
            if dim == 1
                greyim = noisyim; % image to be processed
            elseif dim == 3 
                filteredim = rgb2ycbcr(noisyim);
                greyim = filteredim(:,:,1); 
                Cbim = filteredim(:,:,2);
                Crim = filteredim(:,:,3);
            end
            filter = fspecial('average',m); % Set a 3*3 average filter
            if dim == 1
                greyim = imfilter(greyim,filter);
                filteredim = greyim;
            else 
                greyim = imfilter(greyim,filter);
                Cbim = imfilter(Cbim,filter);
                Crim = imfilter(Crim,filter);
                
                filteredim(:,:,1) = greyim;
                filteredim(:,:,2) = Cbim;
                filteredim(:,:,3) = Crim;
                filteredim = ycbcr2rgb(filteredim);
            end
        end
        
        % Mosaic
        function mosaicim = im2mosaic(app)
            mosaicim = app.im_data;
            m = 30; % size of mosaic area
            [row,col,dim]=size(mosaicim);
            % Change the size of the image to an integral multiple of 8
            mr = ceil(row/m)*m;
            mc = ceil(col/m)*m;
            mosaicim = imresize(mosaicim,[mr mc]);
            if dim == 1
                greyim = mosaicim; % image to be processed
            elseif dim == 3 
                mosaicim = rgb2ycbcr(mosaicim);
                greyim = mosaicim(:,:,1); 
                Cbim = mosaicim(:,:,2);
                Crim = mosaicim(:,:,3);
            end
            % Calculate the mean value of 50*50 blocks
            % Assing the mean value to a 50*50 block with 1 values
            meanfun = @(block_struct)mean(block_struct.data,'all')*ones(size(block_struct.data));
            if dim == 1
                greyim = blockproc(greyim,[m m],meanfun);
                mosaicim = greyim;
                mosaicim = im2uint8(mosaicim/255);
            else 
                greyim = blockproc(greyim,[m m],meanfun);
                Cbim = blockproc(Cbim,[m m],meanfun);
                Crim = blockproc(Crim,[m m],meanfun);
        
                mosaicim(:,:,1) = greyim;
                mosaicim(:,:,2) = Cbim;
                mosaicim(:,:,3) = Crim;
                mosaicim = ycbcr2rgb(mosaicim);
            end
        end
        
        % Edge Detect
        function edgeim = im2edge(app)
            edgeim = app.im_data;
            [~,~,dim] = size(edgeim);
            if dim == 3
                edgeim = rgb2gray(edgeim);
            end
            edgeim = edge(edgeim,'Canny');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: uploadButton
        function UploadButtonPushed(app, event)
            str = '';
            for index = 1:length(app.extensions)
                str = strcat(str,'*',app.extensions{index},';');
            end
            % Filter the image type supported by Matlab
            [file,path] = uigetfile([str,"Image"]);
            i = find(file=='.');
            app.im_data_name = file(1:i-1); % File name without extension
            full_file = fullfile(path,file);
            if ~ischar(path)
                return; % Return if no file is been selected
            elseif ~exist(full_file,'file')
                    errordlg("File does not exist");
                    return;
            end 
            app.im_data = imread(full_file);
            imshow(app.im_data,'Parent',app.UIAxesInput);
        end

        % Button pushed function: CompressButton
        function CompressButtonPushed(app, event)
            if isempty(app.im_data)
                errordlg('No image is in the input field');
                return;
            end
            % An progress dialog without knowing the finish time
            prgs = uiprogressdlg(app.UIFigure,'Title','Image is being compressed','Indeterminate','on');
            app.processed_data = im2jpeg(app);
            app.processed_data = jpeg2im(app).im;
            imshow(app.processed_data,'Parent',app.UIAxesOutput);
            close(prgs);
        end

        % Button pushed function: DownloadButton
        function DownloadButtonPushed(app, event)
            if isempty(app.processed_data)
                errordlg('No image is in the output field');
                return;
            end
            app.count = app.count+1;
            %selpath = uigetdir;
            selpath = uigetdir('C:\');
            str = sprintf('%s%s%d%s','\new',app.im_data_name,app.count,'.jpg');
            imwrite(app.processed_data,[selpath,str]);
        end

        % Button pushed function: EqualisationButton
        function EqualisationButtonPushed(app, event)
            if isempty(app.im_data)
                errordlg('No image is in the input field');
                return;
            end
            prgs = uiprogressdlg(app.UIFigure,'Title','Image is being equalised','Indeterminate','on');
            app.processed_data = im2histeq(app);
            imshow(app.processed_data,'Parent',app.UIAxesOutput);
            close(prgs);
        end

        % Button pushed function: AverageDenoiseButton
        function AverageDenoiseButtonPushed(app, event)
            if isempty(app.im_data)
                errordlg('No image is in the input field');
                return;
            end
            prgs = uiprogressdlg(app.UIFigure,'Title','Image is being denoised','Indeterminate','on');
            app.processed_data = averagefilter(app);
            imshow(app.processed_data,'Parent',app.UIAxesOutput);
            close(prgs);
        end

        % Button pushed function: GussianNoiseButton
        function GussianNoiseButtonPushed(app, event)
            if isempty(app.im_data)
                errordlg('No image is in the input field');
                return;
            end
            app.processed_data = imnoise(app.im_data,'gaussian',0,0.1);
            imshow(app.processed_data,'Parent',app.UIAxesOutput);
        end

        % Button pushed function: PoissonNoiseButton
        function PoissonNoiseButtonPushed(app, event)
            if isempty(app.im_data)
                errordlg('No image is in the input field');
                return;
            end
            app.processed_data = imnoise(app.im_data,'poisson');
            imshow(app.processed_data,'Parent',app.UIAxesOutput);
        end

        % Button pushed function: PauseNoiseButton
        function PauseNoiseButtonPushed(app, event)
             if isempty(app.im_data)
                errordlg('No image is in the input field');
                return;
            end
            app.processed_data = imnoise(app.im_data,'salt & pepper',0.1);
            imshow(app.processed_data,'Parent',app.UIAxesOutput);           
        end

        % Button pushed function: SpeckleNoiseButton
        function SpeckleNoiseButtonPushed(app, event)
             if isempty(app.im_data)
                errordlg('No image is in the input field');
                return;
            end
            app.processed_data = imnoise(app.im_data,'speckle');
            imshow(app.processed_data,'Parent',app.UIAxesOutput);            
        end

        % Button pushed function: MedianDenoiseButton
        function MedianDenoiseButtonPushed(app, event)
            if isempty(app.im_data)
                errordlg('No image is in the input field');
                return;
            end
            prgs = uiprogressdlg(app.UIFigure,'Title','Image is being denoised','Indeterminate','on');
            app.processed_data = medianfilter(app);
            imshow(app.processed_data,'Parent',app.UIAxesOutput);
            close(prgs);            
        end

        % Button pushed function: MosaicButton
        function MosaicButtonPushed(app, event)
            if isempty(app.im_data)
                errordlg('No image is in the input field');
                return;
            end
            prgs = uiprogressdlg(app.UIFigure,'Title','Image is being blurred','Indeterminate','on');
            app.processed_data = im2mosaic(app);
            imshow(app.processed_data,'Parent',app.UIAxesOutput);
            close(prgs);   
        end

        % Button pushed function: EdgeDetectButton
        function EdgeDetectButtonPushed(app, event)
            if isempty(app.im_data)
                errordlg('No image is in the input field');
                return;
            end
            prgs = uiprogressdlg(app.UIFigure,'Title','Image edge is being detected','Indeterminate','on');
            app.processed_data = im2edge(app);
            imshow(app.processed_data,'Parent',app.UIAxesOutput);
            close(prgs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesInput = uiaxes(app.UIFigure);
            title(app.UIAxesInput, 'Input Image')
            xlabel(app.UIAxesInput, '')
            ylabel(app.UIAxesInput, '')
            app.UIAxesInput.XTick = [];
            app.UIAxesInput.YTick = [];
            app.UIAxesOutput = uiaxes(app.UIFigure);
            title(app.UIAxesOutput, 'Output Image')
            xlabel(app.UIAxesOutput, '')
            ylabel(app.UIAxesOutput, '')
            app.UIAxesOutput.XTick = [];
            app.UIAxesOutput.YTick = [];
            app.uploadButton = uibutton(app.UIFigure, 'push');
            app.uploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.uploadButton.Text = 'upload';
            app.DownloadButton = uibutton(app.UIFigure, 'push');
            app.DownloadButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadButtonPushed, true);
            app.DownloadButton.Text = 'Download';
            app.CompressButton = uibutton(app.UIFigure, 'push');
            app.CompressButton.ButtonPushedFcn = createCallbackFcn(app, @CompressButtonPushed, true);
            app.CompressButton.Text = 'Compress';
            app.EqualisationButton = uibutton(app.UIFigure, 'push');
            app.EqualisationButton.ButtonPushedFcn = createCallbackFcn(app, @EqualisationButtonPushed, true);
            app.EqualisationButton.Text = 'Equalisation';
            app.GussianNoiseButton = uibutton(app.UIFigure, 'push');
            app.GussianNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @GussianNoiseButtonPushed, true);
            app.GussianNoiseButton.Text = 'Gussian Noise';
            app.AverageDenoiseButton = uibutton(app.UIFigure, 'push');
            app.AverageDenoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AverageDenoiseButtonPushed, true);
            app.AverageDenoiseButton.Text = 'Average Denoise';
            app.PoissonNoiseButton = uibutton(app.UIFigure, 'push');
            app.PoissonNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @PoissonNoiseButtonPushed, true);
            app.PoissonNoiseButton.Text = 'Poisson Noise';
            app.PauseNoiseButton = uibutton(app.UIFigure, 'push');
            app.PauseNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseNoiseButtonPushed, true);
            app.PauseNoiseButton.Text = 'Pause Noise';
            app.SpeckleNoiseButton = uibutton(app.UIFigure, 'push');
            app.SpeckleNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @SpeckleNoiseButtonPushed, true);
            app.SpeckleNoiseButton.Text = 'Speckle Noise ';
            app.MedianDenoiseButton = uibutton(app.UIFigure, 'push');
            app.MedianDenoiseButton.ButtonPushedFcn = createCallbackFcn(app, @MedianDenoiseButtonPushed, true);
            app.MedianDenoiseButton.Text = 'Median Denoise';
            app.MosaicButton = uibutton(app.UIFigure, 'push');
            app.MosaicButton.ButtonPushedFcn = createCallbackFcn(app, @MosaicButtonPushed, true);
            app.MosaicButton.Text = 'Mosaic';
            app.EdgeDetectButton = uibutton(app.UIFigure, 'push');
            app.EdgeDetectButton.ButtonPushedFcn = createCallbackFcn(app, @EdgeDetectButtonPushed, true);
            app.EdgeDetectButton.Text = 'Edge Detect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageProcessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end